classdef StewartPlatformGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        StewartPlatformStatsPanel       matlab.ui.container.Panel
        UITable_2                       matlab.ui.control.Table
        UITable_PlatformJoints          matlab.ui.control.Table
        StewartPlatSimPanel             matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        TranslationandRotationInputPanel  matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        LiveInputCheckBox               matlab.ui.control.CheckBox
        ZSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YawSpinner                      matlab.ui.control.Spinner
        YawSpinnerLabel                 matlab.ui.control.Label
        RollSpinner                     matlab.ui.control.Spinner
        RollSpinnerLabel                matlab.ui.control.Label
        PitchSpinner                    matlab.ui.control.Spinner
        PitchSpinnerLabel               matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        BaseandPlatformParametersPanel  matlab.ui.container.Panel
        BaseAngle6EditField             matlab.ui.control.NumericEditField
        BaseAngle6EditFieldLabel        matlab.ui.control.Label
        BaseAngle5EditField             matlab.ui.control.NumericEditField
        BaseAngle5EditFieldLabel        matlab.ui.control.Label
        BaseAngle4EditField             matlab.ui.control.NumericEditField
        BaseAngle4EditFieldLabel        matlab.ui.control.Label
        BaseAngle3EditField             matlab.ui.control.NumericEditField
        BaseAngle3EditFieldLabel        matlab.ui.control.Label
        BaseAngle2EditField             matlab.ui.control.NumericEditField
        BaseAngle2EditFieldLabel        matlab.ui.control.Label
        BaseAngle1EditField             matlab.ui.control.NumericEditField
        BaseAngle1EditFieldLabel        matlab.ui.control.Label
        DefaultPlatformZEditField       matlab.ui.control.NumericEditField
        DefaultPlatformZEditFieldLabel  matlab.ui.control.Label
        AngledRodLengthEditField        matlab.ui.control.NumericEditField
        AngledRodLengthEditFieldLabel   matlab.ui.control.Label
        HomeActuatorLengthEditField     matlab.ui.control.NumericEditField
        HomeActuatorLengthEditFieldLabel  matlab.ui.control.Label
        MaxActuatorLengthEditField      matlab.ui.control.NumericEditField
        MaxActuatorLengthEditFieldLabel  matlab.ui.control.Label
        MinActuatorLengthEditField      matlab.ui.control.NumericEditField
        MinActuatorLengthEditFieldLabel  matlab.ui.control.Label
        PlatformLegRadiusEditField      matlab.ui.control.NumericEditField
        PlatformLegRadiusEditFieldLabel  matlab.ui.control.Label
        BaseLegRadiusEditField          matlab.ui.control.NumericEditField
        BaseLegRadiusEditFieldLabel     matlab.ui.control.Label
        DSimgoeshereLabel               matlab.ui.control.Label
        CalculatedTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        ValidityPanel                   matlab.ui.container.Panel
        UITable_Calc_Validity           matlab.ui.control.Table
        ActuatorPanel                   matlab.ui.container.Panel
        UITable_Calc_Actuator           matlab.ui.control.Table
        PlatformJointCoordsBaseBasisPanel  matlab.ui.container.Panel
        UITable_Calc_PlatformJointCoordsBaseBasis  matlab.ui.control.Table
        LinearActuatorBottomCoordsBaseBasisPanel  matlab.ui.container.Panel
        UITable_Calc_LinearActuatorCoordsBaseBasis  matlab.ui.control.Table
        RotatedPlatformJointCoordsPlatformBasisPanel  matlab.ui.container.Panel
        UITable_Calc_RotatedPlatformJointCoordsPlatformBasis  matlab.ui.control.Table
        PlatformJointCoordsPlatformBasisPanel  matlab.ui.container.Panel
        UITable_Calc_PlatformJointCoordsPlatformBasis  matlab.ui.control.Table
        TranslatedPlatformJointCoordsBaseBasisPanel  matlab.ui.container.Panel
        UITable_Calc_PlatformJointCoords  matlab.ui.control.Table
        PlatformJointCoordshomeBaseBasisPanel  matlab.ui.container.Panel
        UITable_Calc_PlatformJointCoordsAtHome  matlab.ui.control.Table
        IntermediateStepsLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.XSpinner.Value = 0;
            app.YSpinner.Value = 0;
            app.ZSpinner.Value = 0;
            app.RollSpinner.Value = 0;
            app.PitchSpinner.Value = 0;
            app.YawSpinner.Value = 0;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % runs all calculated on demand (NOT POLLED)
            
            % TODO: calculate the DefaultPlatformZEditField before everything else
            
            % for table [1,1]
            PLREF = app.PlatformLegRadiusEditField.Value;
            DPZEF = app.DefaultPlatformZEditField.Value;
            
            app.UITable_Calc_PlatformJointCoordsAtHome.Data = [
                1 PLREF*(cosd(0))   PLREF*(sind(0))   DPZEF;
                2 PLREF*(cosd(60))  PLREF*(sind(60))  DPZEF;
                3 PLREF*(cosd(120)) PLREF*(sind(120)) DPZEF;
                4 PLREF*(cosd(180)) PLREF*(sind(180)) DPZEF;
                5 PLREF*(cosd(240)) PLREF*(sind(240)) DPZEF;
                6 PLREF*(cosd(300)) PLREF*(sind(300)) DPZEF;
            ]; 
            
            calc_PJCAH = app.UITable_Calc_PlatformJointCoordsAtHome.Data;
            x_trans = app.XSpinner.Value;
            y_trans = app.YSpinner.Value;
            z_trans = app.ZSpinner.Value;
            
            % for table [2,1]
            app.UITable_Calc_PlatformJointCoords.Data = [
                1 calc_PJCAH(1,2)+x_trans calc_PJCAH(1,3)+y_trans calc_PJCAH(1,4)+z_trans;
                2 calc_PJCAH(2,2)+x_trans calc_PJCAH(2,3)+y_trans calc_PJCAH(2,4)+z_trans;
                3 calc_PJCAH(3,2)+x_trans calc_PJCAH(3,3)+y_trans calc_PJCAH(3,4)+z_trans;
                4 calc_PJCAH(4,2)+x_trans calc_PJCAH(4,3)+y_trans calc_PJCAH(4,4)+z_trans;
                5 calc_PJCAH(5,2)+x_trans calc_PJCAH(5,3)+y_trans calc_PJCAH(5,4)+z_trans;
                6 calc_PJCAH(6,2)+x_trans calc_PJCAH(6,3)+y_trans calc_PJCAH(6,4)+z_trans;
            ];
            
            % for table[3,1]
            app.UITable_Calc_PlatformJointCoordsPlatformBasis.Data = [
              1; 
              2; 
              3;
              4;
              5;
              6;
            ];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout = uigridlayout(app.MainTab);
            app.DSimgoeshereLabel = uilabel(app.GridLayout);
            app.DSimgoeshereLabel.Text = '3D Sim goes here';
            app.BaseandPlatformParametersPanel = uipanel(app.GridLayout);
            app.BaseandPlatformParametersPanel.Title = 'Base and Platform Parameters';
            app.BaseLegRadiusEditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.BaseLegRadiusEditFieldLabel.Text = 'Base Leg Radius';
            app.BaseLegRadiusEditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.BaseLegRadiusEditField.Tooltip = {'Radius of the base legs'};
            app.BaseLegRadiusEditField.Value = 250;
            app.PlatformLegRadiusEditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.PlatformLegRadiusEditFieldLabel.Text = 'Platform Leg Radius';
            app.PlatformLegRadiusEditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.PlatformLegRadiusEditField.Value = 150;
            app.MinActuatorLengthEditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.MinActuatorLengthEditFieldLabel.Text = 'Min Actuator Length';
            app.MinActuatorLengthEditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.MinActuatorLengthEditField.Value = 200;
            app.MaxActuatorLengthEditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.MaxActuatorLengthEditFieldLabel.Text = 'Max Actuator Length';
            app.MaxActuatorLengthEditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.MaxActuatorLengthEditField.Value = 400;
            app.HomeActuatorLengthEditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.HomeActuatorLengthEditFieldLabel.Text = 'Home Actuator Length';
            app.HomeActuatorLengthEditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.HomeActuatorLengthEditField.Value = 300;
            app.AngledRodLengthEditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.AngledRodLengthEditFieldLabel.Text = 'Angled Rod Length';
            app.AngledRodLengthEditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.AngledRodLengthEditField.Value = 200;
            app.DefaultPlatformZEditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.DefaultPlatformZEditFieldLabel.Text = 'Default Platform Z';
            app.DefaultPlatformZEditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.DefaultPlatformZEditField.Editable = 'off';
            app.DefaultPlatformZEditField.Value = 467.9946444;
            app.BaseAngle1EditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.BaseAngle1EditFieldLabel.Text = 'Base Angle 1';
            app.BaseAngle1EditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.BaseAngle1EditField.Editable = 'off';
            app.BaseAngle1EditField.Value = 347.5;
            app.BaseAngle2EditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.BaseAngle2EditFieldLabel.Text = 'Base Angle 2';
            app.BaseAngle2EditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.BaseAngle2EditField.Editable = 'off';
            app.BaseAngle2EditField.Value = 72.5;
            app.BaseAngle3EditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.BaseAngle3EditFieldLabel.Text = 'Base Angle 3';
            app.BaseAngle3EditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.BaseAngle3EditField.Editable = 'off';
            app.BaseAngle3EditField.Value = 107.5;
            app.BaseAngle4EditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.BaseAngle4EditFieldLabel.Text = 'Base Angle 4';
            app.BaseAngle4EditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.BaseAngle4EditField.Editable = 'off';
            app.BaseAngle4EditField.Value = 192.5;
            app.BaseAngle5EditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.BaseAngle5EditFieldLabel.Text = 'Base Angle 5';
            app.BaseAngle5EditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.BaseAngle5EditField.Editable = 'off';
            app.BaseAngle5EditField.Value = 227.5;
            app.BaseAngle6EditFieldLabel = uilabel(app.BaseandPlatformParametersPanel);
            app.BaseAngle6EditFieldLabel.Text = 'Base Angle 6';
            app.BaseAngle6EditField = uieditfield(app.BaseandPlatformParametersPanel, 'numeric');
            app.BaseAngle6EditField.Editable = 'off';
            app.BaseAngle6EditField.Value = 312.5;
            app.TranslationandRotationInputPanel = uipanel(app.GridLayout);
            app.TranslationandRotationInputPanel.Title = 'Translation and Rotation Input';
            app.DefaultButton = uibutton(app.TranslationandRotationInputPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'Default';
            app.PitchSpinnerLabel = uilabel(app.TranslationandRotationInputPanel);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.TranslationandRotationInputPanel);
            app.PitchSpinner.Step = 0.01;
            app.RollSpinnerLabel = uilabel(app.TranslationandRotationInputPanel);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.TranslationandRotationInputPanel);
            app.RollSpinner.Step = 0.01;
            app.YawSpinnerLabel = uilabel(app.TranslationandRotationInputPanel);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.TranslationandRotationInputPanel);
            app.YawSpinner.Step = 0.01;
            app.YSpinnerLabel = uilabel(app.TranslationandRotationInputPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.TranslationandRotationInputPanel);
            app.XSpinnerLabel = uilabel(app.TranslationandRotationInputPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.TranslationandRotationInputPanel);
            app.ZSpinnerLabel = uilabel(app.TranslationandRotationInputPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.TranslationandRotationInputPanel);
            app.LiveInputCheckBox = uicheckbox(app.TranslationandRotationInputPanel);
            app.LiveInputCheckBox.Enable = 'off';
            app.LiveInputCheckBox.Text = {'Live '; 'Input'};
            app.RunButton = uibutton(app.TranslationandRotationInputPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StewartPlatSimPanel = uipanel(app.GridLayout);
            app.StewartPlatSimPanel.Title = 'Stewart Plat Sim';
            app.UIAxes = uiaxes(app.StewartPlatSimPanel);
            title(app.UIAxes, {'Stewart Platform Simulation'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StewartPlatformStatsPanel = uipanel(app.GridLayout);
            app.StewartPlatformStatsPanel.Title = 'Stewart Platform Stats';
            app.UITable_PlatformJoints = uitable(app.StewartPlatformStatsPanel);
            app.UITable_PlatformJoints.ColumnName = {'Leg'; 'X'; 'Y'; 'Z'};
            app.UITable_PlatformJoints.RowName = {'1,2,3,4,5,6'; ''};
            app.UITable_2 = uitable(app.StewartPlatformStatsPanel);
            app.UITable_2.ColumnName = {'Leg'; 'Actuator Length'; 'Actuator Speed'};
            app.UITable_2.RowName = {'1,2,3,4,5,6'; ''};
            app.CalculatedTab = uitab(app.TabGroup);
            app.CalculatedTab.Title = 'Calculated';
            app.GridLayout2 = uigridlayout(app.CalculatedTab);
            app.IntermediateStepsLabel = uilabel(app.GridLayout2);
            app.IntermediateStepsLabel.Text = 'Intermediate Steps';
            app.PlatformJointCoordshomeBaseBasisPanel = uipanel(app.GridLayout2);
            app.PlatformJointCoordshomeBaseBasisPanel.Title = 'Platform Joint Coords @ home - Base Basis';
            app.UITable_Calc_PlatformJointCoordsAtHome = uitable(app.PlatformJointCoordshomeBaseBasisPanel);
            app.UITable_Calc_PlatformJointCoordsAtHome.ColumnName = {'Joint'; 'X'; 'Y'; 'Z'};
            app.UITable_Calc_PlatformJointCoordsAtHome.RowName = {};
            app.TranslatedPlatformJointCoordsBaseBasisPanel = uipanel(app.GridLayout2);
            app.TranslatedPlatformJointCoordsBaseBasisPanel.Title = 'Translated Platform Joint Coords - Base Basis';
            app.UITable_Calc_PlatformJointCoords = uitable(app.TranslatedPlatformJointCoordsBaseBasisPanel);
            app.UITable_Calc_PlatformJointCoords.ColumnName = {'Joint'; 'X'; 'Y'; 'Z'};
            app.UITable_Calc_PlatformJointCoords.RowName = {};
            app.PlatformJointCoordsPlatformBasisPanel = uipanel(app.GridLayout2);
            app.PlatformJointCoordsPlatformBasisPanel.Title = 'Platform Joint Coords - Platform Basis';
            app.RotatedPlatformJointCoordsPlatformBasisPanel = uipanel(app.GridLayout2);
            app.RotatedPlatformJointCoordsPlatformBasisPanel.Title = 'Rotated Platform Joint Coords - Platform Basis';
            app.UITable_Calc_RotatedPlatformJointCoordsPlatformBasis = uitable(app.RotatedPlatformJointCoordsPlatformBasisPanel);
            app.UITable_Calc_RotatedPlatformJointCoordsPlatformBasis.ColumnName = {'Joint'; 'X'; 'Y'; 'Z'};
            app.UITable_Calc_RotatedPlatformJointCoordsPlatformBasis.RowName = {};
            app.LinearActuatorBottomCoordsBaseBasisPanel = uipanel(app.GridLayout2);
            app.LinearActuatorBottomCoordsBaseBasisPanel.Title = 'Linear Actuator Bottom Coords - Base Basis';
            app.UITable_Calc_LinearActuatorCoordsBaseBasis = uitable(app.LinearActuatorBottomCoordsBaseBasisPanel);
            app.UITable_Calc_LinearActuatorCoordsBaseBasis.ColumnName = {'Joint'; 'X'; 'Y'; 'Z'};
            app.UITable_Calc_LinearActuatorCoordsBaseBasis.RowName = {};
            app.PlatformJointCoordsBaseBasisPanel = uipanel(app.GridLayout2);
            app.PlatformJointCoordsBaseBasisPanel.Title = 'Platform Joint Coords - Base Basis';
            app.UITable_Calc_PlatformJointCoordsBaseBasis = uitable(app.PlatformJointCoordsBaseBasisPanel);
            app.UITable_Calc_PlatformJointCoordsBaseBasis.ColumnName = {'Joint'; 'X'; 'Y'; 'Z'};
            app.UITable_Calc_PlatformJointCoordsBaseBasis.RowName = {};
            app.ActuatorPanel = uipanel(app.GridLayout2);
            app.ActuatorPanel.Title = 'Actuator';
            app.UITable_Calc_Actuator = uitable(app.ActuatorPanel);
            app.UITable_Calc_Actuator.ColumnName = {'Joint'; 'X'; 'Y'; 'Z'};
            app.UITable_Calc_Actuator.RowName = {};
            app.ValidityPanel = uipanel(app.GridLayout2);
            app.ValidityPanel.Title = 'Validity';
            app.UITable_Calc_Validity = uitable(app.ValidityPanel);
            app.UITable_Calc_Validity.ColumnName = {'Joint'; '??'; 'Final Length'};
            app.UITable_Calc_Validity.RowName = {};
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.Title = 'Panel';
            app.PlatformJointCoordsPlatformBasisPanel = uipanel(app.GridLayout2);
            app.PlatformJointCoordsPlatformBasisPanel.Title = 'Platform Joint Coords - Platform Basis';
            app.UITable_Calc_PlatformJointCoordsPlatformBasis = uitable(app.PlatformJointCoordsPlatformBasisPanel);
            app.UITable_Calc_PlatformJointCoordsPlatformBasis.ColumnName = {'Joint'; 'X'; 'Y'; 'Z'};
            app.UITable_Calc_PlatformJointCoordsPlatformBasis.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StewartPlatformGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end